classdef wk < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        LeftPanel        matlab.ui.container.Panel
        Button           matlab.ui.control.StateButton
        yEditField       matlab.ui.control.NumericEditField
        yEditFieldLabel  matlab.ui.control.Label
        xEditField       matlab.ui.control.NumericEditField
        xLabel           matlab.ui.control.Label
        RightPanel       matlab.ui.container.Panel
        Label            matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)

            num1=app.xEditField.Value
            num2=app.yEditField.Value
            n_led_per_dim = 4;
            room_size = 10;
            margin = 2;
            points = linspace(margin, room_size - margin, n_led_per_dim); 
            [X, Y] = meshgrid(points, points);
            led_coords = [X(:), Y(:)]; 
   
            num_leds = size(led_coords, 1);
            receiver_true_pos = [num1, num2];
            P_tx = 1; 
            num_leds = size(led_coords, 1);
            rss_values = zeros(1, num_leds);
            for i = 1:num_leds
            dist = sqrt(sum((receiver_true_pos - led_coords(i, :)).^2));
            noise = (rand() - 0.5) * 0.001; 
            rss_values(i) = (P_tx / (dist^2)) + noise;
            end
   
            k = 3;
            estimated_pos_wknn = find_weighted_knn_pos(led_coords, rss_values, k);

            function estimated_pos = find_weighted_knn_pos(led_coordinates, rss_measurements, k)
    
                    [sorted_rss, sorted_indices] = sort(rss_measurements, 'descend');
                    top_k_rss = sorted_rss(1:k);
                    top_k_indices = sorted_indices(1:k);
                    top_k_coords = led_coordinates(top_k_indices, :);
                    fprintf('\n[Wk-NN (k=%d) 算法]: 选中的 %d 个最强 LED:\n', k, k);
                    for i = 1:k
                        fprintf('  - LED %2d (%s), RSS: %.4f\n', ...
                                top_k_indices(i), mat2str(top_k_coords(i,:)), top_k_rss(i));
                    end
                    weights = top_k_rss;
                    if isrow(weights)
                        weights = weights'; % 转换为 kx1
                    end
                
                  
                    weighted_sum_pos = [0, 0];
                    total_weight = 0;
                    
                    for i = 1:k
                        w = weights(i);
                        coord = top_k_coords(i, :);
                        
                        weighted_sum_pos = weighted_sum_pos + (w * coord);
                        total_weight = total_weight + w;
                    end
                    estimated_pos = weighted_sum_pos / total_weight;
                
            end

            error_wknn = sqrt(sum((receiver_true_pos - estimated_pos_wknn).^2));

            app.Label.Text = sprintf("Wk-NN 估计位置: (%.2f, %.2f)",estimated_pos_wknn(1), estimated_pos_wknn(2));
            cla(app.UIAxes); 
            
            % --- 重新设置坐标轴 ---
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            xlim(app.UIAxes, [0 10]);
            ylim(app.UIAxes, [0 10]);
            
            % --- 绘制所有 16 个 LED ---
            plot(app.UIAxes, led_coords(:,1), led_coords(:,2), 'b^', ...
                 'MarkerSize', 8, 'MarkerFaceColor', 'b'); 
            
            % --- 绘制真实位置 ---
            plot(app.UIAxes, receiver_true_pos(1), receiver_true_pos(2), 'ro', ...
                 'MarkerSize', 10, 'MarkerFaceColor', 'r');
           
            
            % --- 绘制 Wk-NN 估计位置 ---
            plot(app.UIAxes, estimated_pos_wknn(1), estimated_pos_wknn(2), 'm*', ...
                 'MarkerSize', 12, 'LineWidth', 2);
            
            % --- 绘制误差线 ---
            line(app.UIAxes, [receiver_true_pos(1), estimated_pos_wknn(1)], ...
                 [receiver_true_pos(2), estimated_pos_wknn(2)], ...
                 'Color', 'm', 'LineStyle', '--');
            
            % --- 设置标题、标签和图例 ---
            title(app.UIAxes, sprintf('加权k-NN(Wk-NN) (k=%d)', k));
            xlabel(app.UIAxes, 'X 坐标 (m)');
            ylabel(app.UIAxes, 'Y 坐标 (m)');
            legend(app.UIAxes, 'LED', '真实位置',  'Wk-NN 估计', 'Wk-NN 误差', 'Location', 'best');
            
            % --- 结束绘图 ---
            hold(app.UIAxes, 'off');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.xLabel = uilabel(app.LeftPanel);
            app.xLabel.Text = '实际坐标x';
            app.xEditField = uieditfield(app.LeftPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.LeftPanel);
            app.yEditFieldLabel.Text = '实际坐标y';
            app.yEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Button = uibutton(app.LeftPanel, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '开始仿真';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '仿真结果';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wk

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end